classdef VALKYRIE_v1 < matlab.apps.AppBase
    %VALKYRIE_V1 Data aggregation tool for porpoise detection data
    %   The app allows batch upload of C-POD and F-POD data (CP1/3 and
    %   FP1/3 files) as well as metadata input related to each deployment
    %   into the European Tracking Network data repository with further
    %   ingestion into EDITOinfra.
    %   
    %   If agreed to, the input files will be stored in the Marine Data
    %   Archive.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        FileselectionTab          matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        FilesSelectedTextArea_2   matlab.ui.control.TextArea
        SelectmetadatafileButton  matlab.ui.control.Button
        ConfirmselectionButton    matlab.ui.control.Button
        FilesSelectedTextArea     matlab.ui.control.TextArea
        SelectCFPODfilesButton    matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        MetadataTab               matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ProcessfilenamemetadatamatchButton  matlab.ui.control.Button
        ChangefilesButton         matlab.ui.control.Button
        OutputTab                 matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ProcessingMessage         matlab.ui.control.TextArea
        SaveoutputfilesButton     matlab.ui.control.Button
    end

    %============================
    % Properties for storing data
    %============================
    properties (Access = private)
        check            % Used for defining if CP1 or FP1 files are included
        SelectedFilePath % Path of the selected file
        files            % input file names for all files to load
        path             % path to files to be used in CP3read_DTO   
        filtFiles        % filtered filenames (only on copy from each pair)
        Config           % Content form json configuration file
        minDate          % minimum date for deployments 
        spec             % Metadata specifications
        MetaData         % Metadata table
        MetaRoles        % Column names for metadata validation (datetime and positions)
        MandatoryFields  % array of the mandatory metadata fields
        OutputOrder      % array of th eorder of all metadata fields
        dtFormat         % date time format from configuration file
        processList      % List of files to be processed after metadata matching
        ProcessingStatus % Store info about whether a file successfully processed
        MetadataOverviewTable matlab.ui.control.Table % Used to display metadata table in app
        Detections       % detections extraxted from input files
        Receivers
        OverviewTable     matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add paths to folder with configuration files and helper
            % scripts
            basePath = fileparts(mfilename('fullpath'));
            foldersToAdd = {'helpers', 'config', 'graphics'};
            
            for i = 1:numel(foldersToAdd)
                folderPath = fullfile(basePath, foldersToAdd{i});
                if isfolder(folderPath)
                    addpath(genpath(folderPath));
                else
                    warning('Folder not found: %s', folderPath);
                end
            end
            
            app.check = 1; % if = 1: will check if both C/FP1 and C/FP3 files are included

            % Load .json cofiguration file
            cfg = fullfile(basePath,'config','metadata_validation.json');
            try
                configText = fileread(cfg);
            catch
                uialert(app.UIFigure,  msg, 'MissingConfig');
                return
            end
            app.Config = jsondecode(configText);

            % Specify the minimum expected date for detection data
            % Can be changed in the configuration file
            app.minDate = datetime(app.Config.MinDate, 'TimeZone', 'UTC');

            app.spec = app.Config.MetadataSpec; % mandatory field names for metadata and the data type - it is a structure where each field is the column names and each has a 1x1 struct with the field type (and options if field type is Predefined)
            
            app.dtFormat = app.Config.datetimeFormats; % get the format of datetime that is accepted

            app.MandatoryFields = app.Config.MandatoryFields;

            app.OutputOrder = app.Config.OutputOrder;

            % Specify which fields are used for metadata validation
            roles = struct();

            fields = fieldnames(app.spec);
            for f = 1:numel(fields)
                fname = fields{f};
                entry = app.spec.(fname);
                if isfield(entry,'Role')
                    roles.(entry.Role) = fname;  % map role → actual column name
                end
            end
            
            app.MetaRoles = roles;

            % Prepare empty table for detections
            app.Detections = table();

            disp('Loaded roles:'); % DEBUGGING
            disp(app.MetaRoles); % DEBUGGING
            disp('Output order:');
            disp(app.OutputOrder);
            disp('Mandatory fields:');
            disp(app.MandatoryFields);
            
            % Below is purely visual:
            % --- Add a panel that covers only the tab headers
            headerCover = uipanel(app.UIFigure, ...
                'BackgroundColor',[0.94 0.94 0.94], ... % same as UIFigure default gray
                'BorderType','none', ...
                'Units','pixels', ...
                'Position',[app.TabGroup.Position(1) ...
                            app.TabGroup.Position(2)+app.TabGroup.Position(4)-25 ...
                            app.TabGroup.Position(3) ...
                            25]);
            
            % Optional: bring the cover panel to the top
            uistack(headerCover,'top');
        end

        % Button pushed function: SelectCFPODfilesButton
        function SelectCFPODfilesButtonPushed(app, event)
            % =====================
            % |POD file selection |
            % =====================
            % GOAL: Allow user to upload files from several deployments

            % TO DO: set a limit of files that can be uploaded at the same
            % time

            validExt = {'.CP1', '.CP3', '.FP1', '.FP3'};

            % Function where user can upload either single or multiple
            % files
            [app.files, app.path] = fileSelect(validExt);

            if isempty(app.files)
                return;
            end

            if ischar(app.files)
                app.files = {app.files}; % ensure files is in a cell array
            end
   
            app.FilesSelectedTextArea.Value = app.files; % Display file names of selected files

            % Check that all file pairs are complete and only POD files are
            % selected - if no tgive user a error message
            [isValid, ~, msg] = checkFileExtension(app.files, app.check); % app.check == 1 means that the function will evaluate if all pod pairs are complete

            % DIsplay the error message
            if ~isValid
                uialert(app.UIFigure, msg, 'File Type Error');
                return;
            end
                
            % Filter for metadata entry (.CP3 or .FP3) - basically just
            % takes away the CP1/FP1 copy of a filename
            isP3 = endsWith(app.files,'.CP3','IgnoreCase',true) | ...
                endsWith(app.files,'.FP3','IgnoreCase',true);
            app.filtFiles = app.files(isP3);

            app.SelectmetadatafileButton.Enable = 'on';

        end

        % Button pushed function: SelectmetadatafileButton
        function SelectmetadatafileButtonPushed(app, event)
            % ===============================
            % Metadata CSV file selection
            % ===============================
            
            % User can select a single metadata file
           [file, path] = uigetfile('*.csv', 'Select Metadata File');
            
            % If user cancels the dialog
            if isequal(file, 0)
                return;
            end
           
            % Display filename
            app.FilesSelectedTextArea_2.Value = {file};
              
            try
                
                tbl = loadMetadataFile(fullfile(path, file)); % load metadata file - will give error if it fails
                
                tbl = checkMetadataColumns(tbl, app.MandatoryFields, app.OutputOrder); % checks for recquired columns in metadata - will give error if it fails. Will add optional columns with no data if missing

                % Check that datetime and coordinates are in correct format
                tbl = validateMetadata(tbl,app.minDate,app.MetaRoles,app.dtFormat,app.MandatoryFields);  % Uses validateDatetime & validateCoordinates functions

                % Find all PODdata that has matching metadata and update
                % metadata file with match info
                % UPDATE: this match has to be done based on the timestamp
                % and pod id in the filename instead of having filename in
                % the metadata
                tbl = matchMetadataWithPOD(app.files,tbl);
                app.MetaData = tbl;                

                app.ConfirmselectionButton.Enable = 'on';
            catch ME
                uialert(app.UIFigure, ME.message, messages('metadataError'));
            end


        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            % ===================================================
            % When file selection has been confirmed... 
            % ===================================================
            % The user will be presented with a table of metadata. It is
            % color coded so matched metadata will have green rows and
            % unmatched metadata will have red rows. 
            % Two buttons at the bottom - one to process matched files and
            % one to go back (cancel) and upload other files and hope for a
            % better match. 

            % Switch to the metadata tab
            app.TabGroup.SelectedTab = app.MetadataTab;

            % Create the metadata overview to view matched metadata - if it
            % doesn't already exist
            if isempty(app.MetadataOverviewTable) || ~isvalid(app.MetadataOverviewTable)
                app.MetadataOverviewTable = uitable(app.GridLayout2);
                app.MetadataOverviewTable.Layout.Row = 1;      % top row (the big area you set to 450 px)
                app.MetadataOverviewTable.Layout.Column = [1 15];  % span full width
                app.MetadataOverviewTable.ColumnSortable = true;
                app.MetadataOverviewTable.FontSize = 14;
            end


           % ---------- Build a readable MatchingFiles column ----------
            % Replace the column with newline-separated string for display
            % while keeping unmatched cases readable.
            if ismember('MatchingFiles', app.MetaData.Properties.VariableNames)
                mf = app.MetaData.MatchingFiles;  % cell array, each cell: cellstr of filenames
                mfDisplay = strings(height(app.MetaData), 1);
        
                hasFileNameCol = ismember('FileName', app.MetaData.Properties.VariableNames);
        
                for r = 1:height(app.MetaData)
                    if ~isempty(mf{r})
                        % Join filenames with newline
                        mfDisplay(r) = strjoin(string(mf{r}), newline);
                    else
                        % Unmatched rows: show informative text if we have FileName (for file-only rows)
                        if hasFileNameCol && strlength(app.MetaData.FileName(r)) > 0
                            mfDisplay(r) = "Unmatched file: " + string(app.MetaData.FileName(r));
                        else
                            mfDisplay(r) = "No matching files";
                        end
                    end
                end
        
                % Replace MatchingFiles column with the display strings
                app.MetaData.MatchingFiles = mfDisplay;
            end


            % ---------- Push data to the table ----------
            app.MetadataOverviewTable.Data = app.MetaData;


            % Make columns auto-size (so long lists are visible)
            app.MetadataOverviewTable.ColumnWidth = 'auto';
        
            % Optional: remove previous styles (if you have a helper for that)
            try
                removeStyle(app.MetadataOverviewTable);
            catch
                % ignore if function not available
            end


            % ---------- Apply row coloring ----------
            % Matched rows: MatchingFiles not equal to "No matching files" (string)
            % Unmatched: "No matching files" or starts with "Unmatched file:"
            mfStr = string(app.MetaData.MatchingFiles);

            % Find rows with matches based on the MatchingFiles not being
            % empty
            iY = find(~cellfun(@isempty, app.MetaData.MatchingFiles));  % Non-empty cell = match
            iN = find(cellfun(@isempty, app.MetaData.MatchingFiles));   % Empty cell = no match

            % Apply the styles
            sY = uistyle('BackgroundColor',[0.85 1.00 0.85]);
            sN = uistyle('BackgroundColor',[1.00 0.85 0.85]);
            if ~isempty(iY), addStyle(app.MetadataOverviewTable,sY,'row',iY); end
            if ~isempty(iN), addStyle(app.MetadataOverviewTable,sN,'row',iN); end

            % Disable buttons
            app.SelectCFPODfilesButton.Enable = 'off';
            app.ConfirmselectionButton.Enable = 'off';
            app.SelectmetadatafileButton.Enable = 'off';

            % Enable buttons
            app.ChangefilesButton.Enable = 'on';
            app.ProcessfilenamemetadatamatchButton.Enable = 'on';
           
        end

        % Button pushed function: ChangefilesButton
        function ChangefilesButtonPushed(app, event)
            %Function to allow user to go back and upload different
            %podfiles and/or metadata file

            % Switch to the file selction tab tab
            app.TabGroup.SelectedTab = app.FileselectionTab;

            % Reset file selection
            app.SelectCFPODfilesButton.Enable = 'on';
            app.ChangefilesButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';
            
            % Optionally clear metadata table
            if ~isempty(app.MetadataOverviewTable) && isvalid(app.MetadataOverviewTable)
                app.MetadataOverviewTable.Data = table();
            end

        end

        % Button pushed function: ProcessfilenamemetadatamatchButton
        function ProcessfilenamemetadatamatchButtonPushed(app, event)
            
            % Function to begin process to extract detections and create
            % detection output and metadata output for upload.


            % =======================
            % Extract detections
            % =======================

            % For each uploaded pod file pair the detections are extracted
            % and put into the format recquired for upload into the ETN
            % database.

            % Make the csv with the information on the receivers used -
            % also in a format accepted for upload to ETN

            % Make an overview table that can be viewde (but optionally
            % saved). This will show the files processed as well as the
            % duration between deployment and data valid until and the
            % duration between the min and max datetimes for each file in
            % the extracted detections data. This will allow the user to
            % get an overview of the data and whether there are any issues
            % between metadata and detection files.

            % ===========================================================
            
            % Disable Change Files button
            app.ChangefilesButton.Enable = 'off';


            % === Build the list of files to process based on metadata matches ===
            nonEmpty = ~cellfun(@isempty, app.MetaData.MatchingFiles);
            if any(nonEmpty)
                % Flatten all matched files into a single list
                matchedFilesAll = string(vertcat(app.MetaData.MatchingFiles{nonEmpty}));
            else
                matchedFilesAll = string([]); % no matches at all
            end
            
            % Work off the filtered CP3/FP3 files to stay consistent with UI selection
            filtFilesStr = string(app.filtFiles(:));
            
            % Intersect to ensure we only process files the user selected AND that have metadata
            app.processList = intersect(filtFilesStr, matchedFilesAll, 'stable');  % 'stable' preserves UI order
            
            % If nothing to process:
            if numel(app.processList) == 0
                uialert(app.UIFigure, ...
                    'No files with matching metadata were found. Please review the metadata and file selection.', ...
                    'No Matched Files');
                return;
            end

            %================
            % Import POD data
            %================

            % For each file pair:
            % % - Import data from both XP1 and XP3 file
            % % - Run summarising/formatting function depending on file
            % type

            % Create progress dialogue
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Processing Files', ...
                'Message', 'Starting...', ...
                'Indeterminate', 'off');

            % Set up timer for progress messages
            nFiles = numel(app.processList);
            tic; % start timer


            % Specififies if the nall will be calculated (only if C/FP1
            % files also uploaded.
            n = '';
            if app.check == 1
                n = '-n';
            end
            
            % Preallocate status array
            app.ProcessingStatus = repmat({'NOT ATTEMPTED'}, nFiles, 1) ;

            % Create a combined table with detections - identifiable by the
            % filename:
            for i = 1:nFiles

                % Update progress
                d.Value = (i-1) / nFiles;
                elapsed = toc;
                avgPerFile = elapsed / max(1, i-1); % average time so far (skip i=1)
                estRemain = avgPerFile * (nFiles - (i-1)); 

                if i > 1
                    timeMsg = sprintf(' (est. %s left)', string(seconds(estRemain), "hh:mm:ss"));
                else
                    timeMsg = '';
                end

                d.Message = [sprintf('Processing file %d of %d...', i, nFiles) timeMsg];

                drawnow; %ensure UI updates

                % get filename and extension
                thisFile = char(app.processList(i));
                [pathCandidate, baseName, ext] = fileparts(thisFile);


                % You already have app.path, but if filenames in processList include full paths,
                    % we can prefer that; otherwise, fall back to app.path.
                    if ~isempty(pathCandidate)
                        usePath = pathCandidate;
                    else
                        usePath = app.path;
                    end
                
                    % DEBUGGING (optional)
                    % disp(usePath)
                    % disp(baseName)
                    % disp([usePath, filesep, baseName, ext])

                % ====================================
                % Read files and summarising functions
                % ====================================

                % % extract and format files depending on their extension
                try % Wrapped in a try-catch to ensure app will not crash if the import and formatting doesn't work for one of the files
                    switch ext
                        case '.CP3'
                            % Import CP3 (and CP1) file
                            tmpMinutes = CP3read_DTO(usePath,baseName,n);
                        case '.FP3'
                            % Import FP3 (and FP1) file
                            % format
                            tmpMinutes = FP3read_DTO(usePath, baseName, n);
                    end

                    % Validate tmpMinutes before conversion to ETN
                    if isempty(tmpMinutes) || isscalar(tmpMinutes)
                        error('NoData:EmptyFile', 'FILE CONTAINS NO DATA');
                    end

                    % Convert imported detections to match ETN format
                    ETN = CP3read_2_etn(tmpMinutes);

                    % Mark as successfully processed
                    app.ProcessingStatus{i} = 'PROCESSED' ;

                catch ME 
                    app.ProcessingStatus{i} = ME.message;
                    continue; % SKip to next file
                end
                
                disp(app.ProcessingStatus) % DEBUGGING

                % TO DO: Check if match between metadata and detections
                % - and what to do if there is a mismatch!
                %if dateshift(app.Active_datetime,'start', 'day') ~= dateshift(ETN.time(1), 'start', 'day') && dateshift(app.Valid_data_until_datetime,'start', 'day') ~= dateshift(ETN.time(end), 'start', 'day')  
                %end

                % Append detection data
                if isempty(app.Detections)
                    app.Detections = ETN;
                else
                    app.Detections = [app.Detections; ETN];
                end
            end

            disp(app.Detections.Properties.VariableNames) % DEBUGGING

            % When processing done, remove column called filename from
            % app.Detections
            % After processing all files and app.Detections is complete
            % if ismember('filename', app.Detections.Properties.VariableNames)
            %     app.Detections.filename = [];  % removes the column
            % end

            % Update message for completed file
            d.Value = 1;
            d.Message = sprintf('File %d of %d completed%s', nFiles, nFiles, timeMsg);
            drawnow;

            % Close progress dialogue
            close(d);

            % === Build processed files overview ===
           % After app.Detections has been built
            overview = makeFileOverview(cellstr(app.processList), app.MetaData, app.Detections, app.ProcessingStatus);
            
            % Show in a table on OutputTab
            if isempty(app.OverviewTable) || ~isvalid(app.OverviewTable)
                app.OverviewTable = uitable(app.GridLayout3, 'Data', overview);
                app.OverviewTable.Layout.Row = [3 11];   % span more rows so it’s big
                app.OverviewTable.Layout.Column = [2 14];
                app.OverviewTable.ColumnSortable = true;
                app.OverviewTable.FontSize = 13;
            else
                app.OverviewTable.Data = overview;
            end

            app.ProcessingMessage.Value = { ...
            'Processing detection files was successful.' ...
            'Here is an overview of the deployment length based on the metadata input and the datetime in detection files.' ...
            'Click to save a file with Receiver information, Metadata and a combined detections output.'};


            % Enable Save Output button
            app.SaveoutputfilesButton.Enable = 'on';

            % Switch to the output tab
            app.TabGroup.SelectedTab = app.OutputTab;
        end

        % Button pushed function: SaveoutputfilesButton
        function SaveoutputfilesButtonPushed(app, event)
            
            % Save cleaned metadata
            % Ask user for base filename and folder
            % TO DO: Does ETN have a recquirement for the file names for
            % detections and metadata
            [baseFile, basePath] = uiputfile('*.csv', 'Save output files as');
            if ischar(baseFile)

                % Remove .csv extension if present
                [~, nameOnly, ~] = fileparts(baseFile);
            
                % Save cleaned metadata

                % Keep only metadata rows (exclude file-only) AND with at least one match
                isMetadataRow = app.MetaData.RowType == "metadata";
                hasMatch      = app.MetaData.MatchCount > 0;
                cleanedMeta   = app.MetaData(isMetadataRow & hasMatch, :);

                app.OutputOrder = app.Config.OutputOrder;
                cleanedMeta = CleanMetadata(cleanedMeta,app.OutputOrder);
                writetable(cleanedMeta, fullfile(basePath, [nameOnly '_metadata.csv']));
            
                % Save combined detections
                writetable(app.Detections, fullfile(basePath, [nameOnly '_detections.csv']));

                % Save receivers 
                [Receivers] = createReceivers(app.MetaData,cellstr(app.processList));
                writetable(Receivers, fullfile(basePath, [nameOnly '_receivers.csv']));

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileselectionTab = uitab(app.TabGroup);
            app.FileselectionTab.Title = 'File selection';
            app.GridLayout = uigridlayout(app.FileselectionTab);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {''; 'VALKYRIE v1.0'; ''; 'Input needed: Owner organisation of the Receiver, folder or files with detections (.CP1 and .CP3 or .FP1 and .FP3) and the corresponding metadata. Please refer to ETN for the correct format for the metadata input [LINK].'};
            app.SelectCFPODfilesButton = uibutton(app.GridLayout, 'push');
            app.SelectCFPODfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCFPODfilesButtonPushed, true);
            app.SelectCFPODfilesButton.Tooltip = {'Click to select .CP1, .CP3, .FP1 and/or .FP3 files to upload.'};
            app.SelectCFPODfilesButton.Text = 'Select C-/F-POD files';
            app.FilesSelectedTextArea = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea.Editable = 'off';
            app.ConfirmselectionButton = uibutton(app.GridLayout, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Enable = 'off';
            app.ConfirmselectionButton.Tooltip = {'Confirm selection of files.'};
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.SelectmetadatafileButton = uibutton(app.GridLayout, 'push');
            app.SelectmetadatafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmetadatafileButtonPushed, true);
            app.SelectmetadatafileButton.Enable = 'off';
            app.SelectmetadatafileButton.Tooltip = {'Click to select metadata .csv file to upload.'};
            app.SelectmetadatafileButton.Text = 'Select metadata file';
            app.FilesSelectedTextArea_2 = uitextarea(app.GridLayout);
            app.FilesSelectedTextArea_2.Editable = 'off';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.GridLayout2 = uigridlayout(app.MetadataTab);
            app.ChangefilesButton = uibutton(app.GridLayout2, 'push');
            app.ChangefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefilesButtonPushed, true);
            app.ChangefilesButton.Enable = 'off';
            app.ChangefilesButton.Text = 'Change files';
            app.ProcessfilenamemetadatamatchButton = uibutton(app.GridLayout2, 'push');
            app.ProcessfilenamemetadatamatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfilenamemetadatamatchButtonPushed, true);
            app.ProcessfilenamemetadatamatchButton.Enable = 'off';
            app.ProcessfilenamemetadatamatchButton.Text = 'Process filename metadata match';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.GridLayout3 = uigridlayout(app.OutputTab);
            app.SaveoutputfilesButton = uibutton(app.GridLayout3, 'push');
            app.SaveoutputfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutputfilesButtonPushed, true);
            app.SaveoutputfilesButton.Enable = 'off';
            app.SaveoutputfilesButton.Text = 'Save output files ';
            app.ProcessingMessage = uitextarea(app.GridLayout3);
            app.ProcessingMessage.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VALKYRIE_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end